classdef transformation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RotationOrderButtonGroup     matlab.ui.container.ButtonGroup
        ZYXButton                    matlab.ui.control.RadioButton
        ZXYButton                    matlab.ui.control.RadioButton
        YZXButton                    matlab.ui.control.RadioButton
        YXZButton                    matlab.ui.control.RadioButton
        XZYButton                    matlab.ui.control.RadioButton
        XYZButton                    matlab.ui.control.RadioButton
        TranslateXmEditField_2       matlab.ui.control.EditField
        TranslateXmEditField_2Label  matlab.ui.control.Label
        TranslateZmEditField         matlab.ui.control.EditField
        TranslateZmEditFieldLabel    matlab.ui.control.Label
        TranslateYmEditField         matlab.ui.control.EditField
        TranslateYmEditFieldLabel    matlab.ui.control.Label
        RotZDegreesEditField         matlab.ui.control.EditField
        RotZDegreesEditFieldLabel    matlab.ui.control.Label
        RotYDegreesEditField         matlab.ui.control.EditField
        RotYDegreesEditFieldLabel    matlab.ui.control.Label
        RotXDegreesEditField         matlab.ui.control.EditField
        RotXDegreesEditFieldLabel    matlab.ui.control.Label
        ResetButton                  matlab.ui.control.Button
        BodyTransformationButton     matlab.ui.control.Button
        SpatialTransformationButton  matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global T_S;
            global T_SB;
            T_S = [[1 0 0 0]; [0 1 0 0]; [0 0 1 0]; [0 0 0 1]];
            T_SB = [[1 0 0 0]; [0 1 0 0]; [0 0 1 0]; [0 0 0 1]];

            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');
            view(app.UIAxes, [45, 30]);
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            zlabel(app.UIAxes, 'Z');

            xlim(app.UIAxes, [-5.0 5.0]);
            ylim(app.UIAxes, [-5.0 5.0]);
            zlim(app.UIAxes, [-5.0 5.0]);

            quiver3(app.UIAxes, T_S(1,4), T_S(2,4), T_S(3,4), T_S(1,1), T_S(2,1), T_S(3,1), 0, 'r', 'LineWidth', 2);
            quiver3(app.UIAxes, T_S(1,4), T_S(2,4), T_S(3,4), T_S(1,2), T_S(2,2), T_S(3,2), 0, 'g', 'LineWidth', 2);
            quiver3(app.UIAxes, T_S(1,4), T_S(2,4), T_S(3,4), T_S(1,3), T_S(2,3), T_S(3,3), 0, 'b','LineWidth',2);
            text(app.UIAxes, T_S(1,4)-0.03, T_S(2,4)-0.03, T_S(3,4)-0.03, 'S-FRAME', 'Color','c','FontSize',12);

            quiver3(app.UIAxes, T_SB(1,4), T_SB(2,4), T_SB(3,4), T_SB(1,1), T_SB(2,1), T_SB(3,1), 0, 'r', 'LineWidth', 2);
            quiver3(app.UIAxes, T_SB(1,4), T_SB(2,4), T_SB(3,4), T_SB(1,2), T_SB(2,2), T_SB(3,2), 0, 'g', 'LineWidth', 2);
            quiver3(app.UIAxes, T_SB(1,4), T_SB(2,4), T_SB(3,4), T_SB(1,3), T_SB(2,3), T_SB(3,3), 0, 'b','LineWidth',2);
            text(app.UIAxes, T_SB(1,4)+0.03, T_SB(2,4)+0.03, T_SB(3,4)+0.03, 'B-FRAME', 'Color','k','FontSize',12);
        end

        % Button pushed function: SpatialTransformationButton
        function SpatialTransformationButtonPushed(app, event)
            global T_S;
            global T_SB;
            T_S = [[1 0 0 0]; [0 1 0 0]; [0 0 1 0]; [0 0 0 1]];
            T_B = [[1 0 0 0]; [0 1 0 0]; [0 0 1 0]; [0 0 0 1]];
            T_SB = [[1 0 0 0]; [0 1 0 0]; [0 0 1 0]; [0 0 0 1]];

            rot_z = str2double(app.RotXDegreesEditField.Value);
            rot_y = str2double(app.RotYDegreesEditField.Value);
            rot_x = str2double(app.RotXDegreesEditField.Value);

            translate_x = str2double(app.TranslateXmEditField_2.Value);
            translate_y = str2double(app.TranslateYmEditField.Value);
            translate_z = str2double(app.TranslateZmEditField.Value);

            T_X = [[1 0 0 0];
                [0 cos(deg2rad(rot_x))  -sin(deg2rad(rot_x)) 0];
                [0 sin(deg2rad(rot_x)) cos(deg2rad(rot_x)) 0];
                [0 0 0 1]];

            T_Y = [[cos(deg2rad(rot_y)) 0 sin(deg2rad(rot_y)) 0];
                [0 1 0 0];
                [-sin(deg2rad(rot_y)) 0 cos(deg2rad(rot_y)) 0];
                [0 0 0 1]];
            
            T_Z = [[cos(deg2rad(rot_z)) -sin(deg2rad(rot_z)) 0 0];
                [sin(deg2rad(rot_z)) cos(deg2rad(rot_z)) 0 0];
                [0 0 1 0];
                [0 0 0 1]];

            if app.RotationOrderButtonGroup.SelectedObject.Value == 1
                T_SB = (T_X * T_Y * T_Z);
            elseif app.RotationOrderButtonGroup.SelectedObject.Value == 2
                T_SB = (T_X * T_Z * T_Y);
            elseif app.RotationOrderButtonGroup.SelectedObject.Value == 3
                T_SB = (T_Y * T_X * T_Z);
            elseif app.RotationOrderButtonGroup.SelectedObject.Value == 4
                T_SB = (T_Y * T_Z * T_X);
            elseif app.RotationOrderButtonGroup.SelectedObject.Value == 5
                T_SB = (T_Z * T_X * T_Y);
            elseif app.RotationOrderButtonGroup.SelectedObject.Value == 6
                T_SB = (T_Z * T_Y * T_X);
            end

            T_SB(1,4) = translate_x;
            T_SB(2,4) = translate_y;
            T_SB(3,4) = translate_z;

            T_B_N = (T_SB * T_B);

            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');
            view(app.UIAxes, [45, 30]);
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            zlabel(app.UIAxes, 'Z');

            xlim(app.UIAxes, [-5.0 5.0]);
            ylim(app.UIAxes, [-5.0 5.0]);
            zlim(app.UIAxes, [-5.0 5.0]);

            quiver3(app.UIAxes, T_S(1,4), T_S(2,4), T_S(3,4), T_S(1,1), T_S(2,1), T_S(3,1), 0, 'r', 'LineWidth', 2);
            quiver3(app.UIAxes, T_S(1,4), T_S(2,4), T_S(3,4), T_S(1,2), T_S(2,2), T_S(3,2), 0, 'g', 'LineWidth', 2);
            quiver3(app.UIAxes, T_S(1,4), T_S(2,4), T_S(3,4), T_S(1,3), T_S(2,3), T_S(3,3), 0, 'b','LineWidth',2);
            text(app.UIAxes, T_S(1,4)-0.03, T_S(2,4)-0.03, T_S(3,4)-0.03, 'S-FRAME', 'Color','c','FontSize',12);

            quiver3(app.UIAxes, T_B_N(1,4), T_B_N(2,4), T_B_N(3,4), T_B_N(1,1), T_B_N(2,1), T_B_N(3,1), 0, 'r', 'LineWidth', 2);
            quiver3(app.UIAxes, T_B_N(1,4), T_B_N(2,4), T_B_N(3,4), T_B_N(1,2), T_B_N(2,2), T_B_N(3,2), 0, 'g', 'LineWidth', 2);
            quiver3(app.UIAxes, T_B_N(1,4), T_B_N(2,4), T_B_N(3,4), T_B_N(1,3), T_B_N(2,3), T_B_N(3,3), 0, 'b','LineWidth',2);
            text(app.UIAxes, T_B_N(1,4)+0.03, T_B_N(2,4)+0.03, T_B_N(3,4)+0.03, 'B-FRAME', 'Color','k','FontSize',12);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global T_S;
            global T_SB;
            T_S = [[1 0 0 0]; [0 1 0 0]; [0 0 1 0]; [0 0 0 1]];
            T_SB = [[1 0 0 0]; [0 1 0 0]; [0 0 1 0]; [0 0 0 1]];

            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');
            view(app.UIAxes, [45, 30]);
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            zlabel(app.UIAxes, 'Z');

            xlim(app.UIAxes, [-5.0 5.0]);
            ylim(app.UIAxes, [-5.0 5.0]);
            zlim(app.UIAxes, [-5.0 5.0]);

            quiver3(app.UIAxes, T_S(1,4), T_S(2,4), T_S(3,4), T_S(1,1), T_S(2,1), T_S(3,1), 0, 'r', 'LineWidth', 2);
            quiver3(app.UIAxes, T_S(1,4), T_S(2,4), T_S(3,4), T_S(1,2), T_S(2,2), T_S(3,2), 0, 'g', 'LineWidth', 2);
            quiver3(app.UIAxes, T_S(1,4), T_S(2,4), T_S(3,4), T_S(1,3), T_S(2,3), T_S(3,3), 0, 'b','LineWidth',2);
            text(app.UIAxes, T_S(1,4)-0.03, T_S(2,4)-0.03, T_S(3,4)-0.03, 'S-FRAME', 'Color','c','FontSize',12);

            quiver3(app.UIAxes, T_SB(1,4), T_SB(2,4), T_SB(3,4), T_SB(1,1), T_SB(2,1), T_SB(3,1), 0, 'r', 'LineWidth', 2);
            quiver3(app.UIAxes, T_SB(1,4), T_SB(2,4), T_SB(3,4), T_SB(1,2), T_SB(2,2), T_SB(3,2), 0, 'g', 'LineWidth', 2);
            quiver3(app.UIAxes, T_SB(1,4), T_SB(2,4), T_SB(3,4), T_SB(1,3), T_SB(2,3), T_SB(3,3), 0, 'b','LineWidth',2);
            text(app.UIAxes, T_SB(1,4)+0.03, T_SB(2,4)+0.03, T_SB(3,4)+0.03, 'B-FRAME', 'Color','k','FontSize',12);
        end

        % Button pushed function: BodyTransformationButton
        function BodyTransformationButtonPushed(app, event)
            global T_S;
            global T_SB;
            T_S = [[1 0 0 0]; [0 1 0 0]; [0 0 1 0]; [0 0 0 1]];
            T_B = [[1 0 0 0]; [0 1 0 0]; [0 0 1 0]; [0 0 0 1]];
            T_SB = [[1 0 0 0]; [0 1 0 0]; [0 0 1 0]; [0 0 0 1]];

            rot_z = str2double(app.RotXDegreesEditField.Value);
            rot_y = str2double(app.RotYDegreesEditField.Value);
            rot_x = str2double(app.RotXDegreesEditField.Value);

            translate_x = str2double(app.TranslateXmEditField_2.Value);
            translate_y = str2double(app.TranslateYmEditField.Value);
            translate_z = str2double(app.TranslateZmEditField.Value);

            T_X = [[1 0 0 0];
                [0 cos(deg2rad(rot_x))  -sin(deg2rad(rot_x)) 0];
                [0 sin(deg2rad(rot_x)) cos(deg2rad(rot_x)) 0];
                [0 0 0 1]];

            T_Y = [[cos(deg2rad(rot_y)) 0 sin(deg2rad(rot_y)) 0];
                [0 1 0 0];
                [-sin(deg2rad(rot_y)) 0 cos(deg2rad(rot_y)) 0];
                [0 0 0 1]];
            
            T_Z = [[cos(deg2rad(rot_z)) -sin(deg2rad(rot_z)) 0 0];
                [sin(deg2rad(rot_z)) cos(deg2rad(rot_z)) 0 0];
                [0 0 1 0];
                [0 0 0 1]];

            if app.RotationOrderButtonGroup.SelectedObject.Value == 1
                T_SB = (T_X * T_Y * T_Z);
            elseif app.RotationOrderButtonGroup.SelectedObject.Value == 2
                T_SB = (T_X * T_Z * T_Y);
            elseif app.RotationOrderButtonGroup.SelectedObject.Value == 3
                T_SB = (T_Y * T_X * T_Z);
            elseif app.RotationOrderButtonGroup.SelectedObject.Value == 4
                T_SB = (T_Y * T_Z * T_X);
            elseif app.RotationOrderButtonGroup.SelectedObject.Value == 5
                T_SB = (T_Z * T_X * T_Y);
            elseif app.RotationOrderButtonGroup.SelectedObject.Value == 6
                T_SB = (T_Z * T_Y * T_X);
            end

            T_SB(1,4) = translate_x;
            T_SB(2,4) = translate_y;
            T_SB(3,4) = translate_z;

            T_B_N = (T_B * T_SB);

            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');
            view(app.UIAxes, [45, 30]);
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            zlabel(app.UIAxes, 'Z');

            xlim(app.UIAxes, [-5.0 5.0]);
            ylim(app.UIAxes, [-5.0 5.0]);
            zlim(app.UIAxes, [-5.0 5.0]);

            quiver3(app.UIAxes, T_S(1,4), T_S(2,4), T_S(3,4), T_S(1,1), T_S(2,1), T_S(3,1), 0, 'r', 'LineWidth', 2);
            quiver3(app.UIAxes, T_S(1,4), T_S(2,4), T_S(3,4), T_S(1,2), T_S(2,2), T_S(3,2), 0, 'g', 'LineWidth', 2);
            quiver3(app.UIAxes, T_S(1,4), T_S(2,4), T_S(3,4), T_S(1,3), T_S(2,3), T_S(3,3), 0, 'b','LineWidth',2);
            text(app.UIAxes, T_S(1,4)-0.03, T_S(2,4)-0.03, T_S(3,4)-0.03, 'S-FRAME', 'Color','c','FontSize',12);

            quiver3(app.UIAxes, T_B_N(1,4), T_B_N(2,4), T_B_N(3,4), T_B_N(1,1), T_B_N(2,1), T_B_N(3,1), 0, 'r', 'LineWidth', 2);
            quiver3(app.UIAxes, T_B_N(1,4), T_B_N(2,4), T_B_N(3,4), T_B_N(1,2), T_B_N(2,2), T_B_N(3,2), 0, 'g', 'LineWidth', 2);
            quiver3(app.UIAxes, T_B_N(1,4), T_B_N(2,4), T_B_N(3,4), T_B_N(1,3), T_B_N(2,3), T_B_N(3,3), 0, 'b','LineWidth',2);
            text(app.UIAxes, T_B_N(1,4)+0.03, T_B_N(2,4)+0.03, T_B_N(3,4)+0.03, 'B-FRAME', 'Color','k','FontSize',12);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'World')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SpatialTransformationButton = uibutton(app.UIFigure, 'push');
            app.SpatialTransformationButton.ButtonPushedFcn = createCallbackFcn(app, @SpatialTransformationButtonPushed, true);
            app.SpatialTransformationButton.Text = 'Spatial Transformation';
            app.BodyTransformationButton = uibutton(app.UIFigure, 'push');
            app.BodyTransformationButton.ButtonPushedFcn = createCallbackFcn(app, @BodyTransformationButtonPushed, true);
            app.BodyTransformationButton.Text = 'Body Transformation';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RotXDegreesEditFieldLabel = uilabel(app.UIFigure);
            app.RotXDegreesEditFieldLabel.Text = 'Rot X (Degrees)';
            app.RotXDegreesEditField = uieditfield(app.UIFigure, 'text');
            app.RotYDegreesEditFieldLabel = uilabel(app.UIFigure);
            app.RotYDegreesEditFieldLabel.Text = 'Rot Y (Degrees)';
            app.RotYDegreesEditField = uieditfield(app.UIFigure, 'text');
            app.RotZDegreesEditFieldLabel = uilabel(app.UIFigure);
            app.RotZDegreesEditFieldLabel.Text = 'Rot Z (Degrees)';
            app.RotZDegreesEditField = uieditfield(app.UIFigure, 'text');
            app.TranslateYmEditFieldLabel = uilabel(app.UIFigure);
            app.TranslateYmEditFieldLabel.Text = 'Translate Y (m)';
            app.TranslateYmEditField = uieditfield(app.UIFigure, 'text');
            app.TranslateZmEditFieldLabel = uilabel(app.UIFigure);
            app.TranslateZmEditFieldLabel.Text = 'Translate Z (m)';
            app.TranslateZmEditField = uieditfield(app.UIFigure, 'text');
            app.TranslateXmEditField_2Label = uilabel(app.UIFigure);
            app.TranslateXmEditField_2Label.Text = 'Translate X (m)';
            app.TranslateXmEditField_2 = uieditfield(app.UIFigure, 'text');
            app.RotationOrderButtonGroup = uibuttongroup(app.UIFigure);
            app.RotationOrderButtonGroup.Title = 'Rotation Order';
            app.XYZButton = uiradiobutton(app.RotationOrderButtonGroup);
            app.XYZButton.Text = 'XYZ';
            app.XYZButton.Value = true;
            app.XZYButton = uiradiobutton(app.RotationOrderButtonGroup);
            app.XZYButton.Text = 'XZY';
            app.YXZButton = uiradiobutton(app.RotationOrderButtonGroup);
            app.YXZButton.Text = 'YXZ';
            app.YZXButton = uiradiobutton(app.RotationOrderButtonGroup);
            app.YZXButton.Text = 'YZX';
            app.ZXYButton = uiradiobutton(app.RotationOrderButtonGroup);
            app.ZXYButton.Text = 'ZXY';
            app.ZYXButton = uiradiobutton(app.RotationOrderButtonGroup);
            app.ZYXButton.Text = 'ZYX';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = transformation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end